classdef design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Label                  matlab.ui.control.Label
        Button                 matlab.ui.control.Button
        HumidityLabel          matlab.ui.control.Label
        TemperatureLabel       matlab.ui.control.Label
        MQ135Label             matlab.ui.control.Label
        MQ135Gauge             matlab.ui.control.Gauge
        MQ135GaugeLabel        matlab.ui.control.Label
        TemperatureGauge       matlab.ui.control.SemicircularGauge
        TemperatureGaugeLabel  matlab.ui.control.Label
        MQ7Gauge               matlab.ui.control.Gauge
        MQ7GaugeLabel          matlab.ui.control.Label
        MQ7Label               matlab.ui.control.Label
        HumidityGauge          matlab.ui.control.SemicircularGauge
        HumidityGaugeLabel     matlab.ui.control.Label
        Lamp                   matlab.ui.control.Lamp
        LampLabel              matlab.ui.control.Label
        DatePicker             matlab.ui.control.DatePicker
        DatePickerLabel        matlab.ui.control.Label
        EndNodeButtonGroup     matlab.ui.container.ButtonGroup
        Node3Button            matlab.ui.control.ToggleButton
        Node2Button            matlab.ui.control.ToggleButton
        Node1Button            matlab.ui.control.ToggleButton
        UIAxes_Humidity        matlab.ui.control.UIAxes
        UIAxes_Temp            matlab.ui.control.UIAxes
        UIAxes_MQ135           matlab.ui.control.UIAxes
        UIAxes_MQ7             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function plotData(app, event)
            date = app.DatePicker.Value ;
            
            %%
            % อ่านข้อมูลจากไฟล์ .db (ใส่ชื่อไฟล์ของคุณ)
            selectedDate = string(date) ;
            obj_date = datetime(selectedDate, 'InputFormat','dd/MMM/yyyy');
            newDateStr = datestr(obj_date, 'dd/mm/yyyy');
            
            conn = sqlite("sensor_data.db");
            data = sqlread(conn, "sensor_data");
            
            if app.Node1Button.Value
                node_select = 1
            elseif app.Node2Button.Value
                node_select = 2
            else
                node_select = 3;
            end
            
            disp("node_select");
            disp(node_select);

            data = data(find((data.Date == newDateStr) & (data.Node_ID == node_select)), :);
            disp("After");
            disp(data);

            mq7 = data.MQ7;        % โหลดข้อมูลจากคอลัมน์ MQ7
            mq135 = data.MQ135;
            temp = data.Temperature;  
            humidity = data.Humidity;
            time = duration(data.Time);

            disp("Time");
            disp(duration(time));
            disp("MQ-7");
            disp(class(mq7));
          
            
            % พล็อตกราฟใน UIAxes แรก (UIAxes_MQ7)
            xtick_time = duration(["00:00:00" "03:00:00" "06:00:00" "09:00:00" "12:00:00" "15:00:00" "18:00:00" "21:00:00"]);
            disp(xtick_time);
            plot(app.UIAxes_MQ7, time, mq7, 'o-', 'LineWidth', 2, 'MarkerSize', 6, 'Color', 'b');
            xticks(app.UIAxes_MQ7, xtick_time);
            xlim(app.UIAxes_MQ7, [xtick_time(1) xtick_time(end)])
            yticks(app.UIAxes_MQ7, 0:100:1000);
            ylim(app.UIAxes_MQ7, [0 1000]);
            title(app.UIAxes_MQ7, 'MQ-7 Sensor Readings');
            xlabel(app.UIAxes_MQ7, 'Time (s)');
            ylabel(app.UIAxes_MQ7, 'MQ-7 Value');
            grid(app.UIAxes_MQ7, 'on');  % เปิดการแสดงกริด
            
            % พล็อตกราฟใน UIAxes (UIAxes_MQ135)
            plot(app.UIAxes_MQ135, time, mq135, 'o-', 'LineWidth', 2, 'MarkerSize', 6, 'Color', 'r');
            xticks(app.UIAxes_MQ135, xtick_time);
            yticks(app.UIAxes_MQ135, 0:4000:44000);
            xlim(app.UIAxes_MQ135, [xtick_time(1) xtick_time(end)])
            ylim(app.UIAxes_MQ135, [0 44000]);
            title(app.UIAxes_MQ135, 'MQ-135 Sensor Readings');
            xlabel(app.UIAxes_MQ135, 'Time (s)');
            ylabel(app.UIAxes_MQ135, 'MQ-135 Value');
            grid(app.UIAxes_MQ135, 'on');  % เปิดการแสดงกริด

            % พล็อตกราฟใน UIAxes (UIAxes_Temp)
            plot(app.UIAxes_Temp, time, temp, 'o-', 'LineWidth', 2, 'MarkerSize', 6, 'Color', 'b');
            xticks(app.UIAxes_Temp, xtick_time);
            yticks(app.UIAxes_Temp, 0:10:100);
            xlim(app.UIAxes_Temp, [xtick_time(1) xtick_time(end)])
            ylim(app.UIAxes_Temp, [0 100]);
            title(app.UIAxes_Temp, 'Temperature Sensor Readings');
            xlabel(app.UIAxes_Temp, 'Time (s)');
            ylabel(app.UIAxes_Temp, 'Temperature Value');
            grid(app.UIAxes_Temp, 'on');  % เปิดการแสดงกริด

            % พล็อตกราฟใน UIAxes (UIAxes_Humidity)
            plot(app.UIAxes_Humidity, time, humidity, 'o-', 'LineWidth', 2, 'MarkerSize', 6, 'Color', 'r');
            xticks(app.UIAxes_Humidity, xtick_time);
            yticks(app.UIAxes_Humidity, 0:10:100);
            xlim(app.UIAxes_Humidity, [xtick_time(1) xtick_time(end)])
            ylim(app.UIAxes_Humidity, [0 100]);
            title(app.UIAxes_Humidity, 'Humidity Sensor Readings');
            xlabel(app.UIAxes_Humidity, 'Time (s)');
            ylabel(app.UIAxes_Humidity, 'Humidity Value');
            grid(app.UIAxes_Humidity, 'on');  % เปิดการแสดงกริด
            %%
        end

        % Callback function
        function ButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_MQ7 = uiaxes(app.UIFigure);
            title(app.UIAxes_MQ7, 'MQ-7 VS Time')
            xlabel(app.UIAxes_MQ7, 'Time (hr.)')
            ylabel(app.UIAxes_MQ7, 'MQ-7')
            zlabel(app.UIAxes_MQ7, 'Z')
            app.UIAxes_MQ7.XTick = [];
            app.UIAxes_MQ7.YTick = [];
            app.UIAxes_MQ135 = uiaxes(app.UIFigure);
            title(app.UIAxes_MQ135, 'MQ-135 VS Time')
            xlabel(app.UIAxes_MQ135, 'Time (hr.)')
            ylabel(app.UIAxes_MQ135, 'MQ-135')
            zlabel(app.UIAxes_MQ135, 'Z')
            app.UIAxes_MQ135.XTick = [];
            app.UIAxes_MQ135.YTick = [];
            app.UIAxes_Temp = uiaxes(app.UIFigure);
            title(app.UIAxes_Temp, 'Temp. VS Time')
            xlabel(app.UIAxes_Temp, 'Time (hr.)')
            ylabel(app.UIAxes_Temp, 'Temp')
            zlabel(app.UIAxes_Temp, 'Z')
            app.UIAxes_Temp.XTick = [];
            app.UIAxes_Temp.YTick = [];
            app.UIAxes_Humidity = uiaxes(app.UIFigure);
            title(app.UIAxes_Humidity, 'Humidity VS Time')
            xlabel(app.UIAxes_Humidity, 'Time (hr.)')
            ylabel(app.UIAxes_Humidity, 'Humid')
            zlabel(app.UIAxes_Humidity, 'Z')
            app.UIAxes_Humidity.XTick = [];
            app.UIAxes_Humidity.YTick = [];
            app.EndNodeButtonGroup = uibuttongroup(app.UIFigure);
            app.EndNodeButtonGroup.Title = 'End Node';
            app.Node1Button = uitogglebutton(app.EndNodeButtonGroup);
            app.Node1Button.Text = 'Node 1';
            app.Node1Button.Value = true;
            app.Node2Button = uitogglebutton(app.EndNodeButtonGroup);
            app.Node2Button.Text = 'Node 2';
            app.Node3Button = uitogglebutton(app.EndNodeButtonGroup);
            app.Node3Button.Text = 'Node 3';
            app.DatePickerLabel = uilabel(app.UIFigure);
            app.DatePickerLabel.Text = 'Date Picker';
            app.DatePicker = uidatepicker(app.UIFigure);
            app.DatePicker.DisplayFormat = 'dd/MMM/uuuu';
            app.DatePicker.Value = datetime([2024 9 18]);
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.HumidityGaugeLabel = uilabel(app.UIFigure);
            app.HumidityGaugeLabel.Text = 'Humidity';
            app.HumidityGauge = uigauge(app.UIFigure, 'semicircular');
            app.MQ7Label = uilabel(app.UIFigure);
            app.MQ7Label.Text = 'MQ-7';
            app.MQ7GaugeLabel = uilabel(app.UIFigure);
            app.MQ7GaugeLabel.Text = 'MQ-7';
            app.MQ7Gauge = uigauge(app.UIFigure, 'circular');
            app.MQ7Gauge.Limits = [0 1000];
            app.TemperatureGaugeLabel = uilabel(app.UIFigure);
            app.TemperatureGaugeLabel.Text = 'Temperature';
            app.TemperatureGauge = uigauge(app.UIFigure, 'semicircular');
            app.MQ135GaugeLabel = uilabel(app.UIFigure);
            app.MQ135GaugeLabel.Text = 'MQ-135';
            app.MQ135Gauge = uigauge(app.UIFigure, 'circular');
            app.MQ135Gauge.Limits = [400 40000];
            app.MQ135Gauge.MajorTicks = [400 20200 40000];
            app.MQ135Gauge.MajorTickLabels = {'400', '20.2k', '40k'};
            app.MQ135Label = uilabel(app.UIFigure);
            app.MQ135Label.Text = 'MQ-135';
            app.TemperatureLabel = uilabel(app.UIFigure);
            app.TemperatureLabel.Text = 'Temperature';
            app.HumidityLabel = uilabel(app.UIFigure);
            app.HumidityLabel.Text = 'Humidity';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @plotData, true);
            app.Label = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = design

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end